classdef cokemenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TotalPaidEditField             matlab.ui.control.NumericEditField
        TotalPaidEditFieldLabel        matlab.ui.control.Label
        NeedtopaymoreEditField         matlab.ui.control.NumericEditField
        NeedtopaymoreEditFieldLabel    matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ProceedtopayInsertMoneyButton  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        YouhaveselectedtobuyTwixMiniChocolateBarPrice5Only5Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ProceedtopayInsertMoneyButton
        function ProceedtopayInsertMoneyButtonPushed(app, event)
             Img_file = uigetfile('*.jpg*','Select input note');
            inp_money = note_identification(Img_file);
            prod_price = 30;
            paid_money = inp_money;
            if paid_money >= prod_price
                ret_money = paid_money - prod_price;
                if ret_money == 0
                    figure
                    Ip = imread("cokecan.png");
                    imshow(Ip)
                    title("Thank you for purchasing. Here is your Product:", "FontName","Cambria Math")
                    delete(app)
                else
                    out_display(ret_money, paid_money);
                    figure
                    Ip = imread("cokecan.png");
                    imshow(Ip)
                    title("Thank you for purchasing. Here is your Product:", "FontName","Cambria Math")
                    delete(app)
                end
            else
                
                total_paid=0;
                app.NeedtopaymoreEditField.Value=50;
                while(total_paid<prod_price)
                    
                    total_paid=total_paid+inp_money;
                    repay = prod_price - total_paid;
                    app.NeedtopaymoreEditField.Value=repay;
                    app.TotalPaidEditField.Value=total_paid;
                    
                    if total_paid>= prod_price
                        ret_money = total_paid - prod_price;
                         if ret_money == 0
                            figure
                            Ip = imread("cokecan.png");
                            imshow(Ip)
                            title("Thank you for purchasing. Here is your Product:", "FontName","Cambria Math")
                            delete(app)
                        else
                            out_display(ret_money, total_paid);
                            figure
                            Ip = imread("cokecan.png");
                            imshow(Ip)
                            title("Thank you for purchasing. Here is your Product:", "FontName","Cambria Math")
                            delete(app)
                         end
                         break
                    end
                    Img_file = uigetfile('*.jpg*','Select input note');
                    inp_money = note_identification(Img_file);
                
                
                end
                
                %delete(app)
                
                
                %ret_money = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YouhaveselectedtobuyTwixMiniChocolateBarPrice5Only5Label = uilabel(app.UIFigure);
            app.YouhaveselectedtobuyTwixMiniChocolateBarPrice5Only5Label.Text = {'You have selected to buy "CocaCola Canned Coke 250ml" '; 'Price: 30 ৳'; ''; 'Only 5 ৳, 10 ৳, 20 ৳ and 50 ৳ notes are allowed to pay the price.'; '           '};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cokecan.png';
            app.ProceedtopayInsertMoneyButton = uibutton(app.UIFigure, 'push');
            app.ProceedtopayInsertMoneyButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtopayInsertMoneyButtonPushed, true);
            app.ProceedtopayInsertMoneyButton.Text = 'Proceed to pay (Insert Money)';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.NeedtopaymoreEditFieldLabel = uilabel(app.UIFigure);
            app.NeedtopaymoreEditFieldLabel.Text = 'Need to pay more ৳';
            app.NeedtopaymoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.NeedtopaymoreEditField.Value = 30;
            app.TotalPaidEditFieldLabel = uilabel(app.UIFigure);
            app.TotalPaidEditFieldLabel.Text = 'Total Paid ৳';
            app.TotalPaidEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cokemenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end